classdef CD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DibujarTrayectoriaButton     matlab.ui.control.Button
        psi                          matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        theta                        matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        phi                          matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Z                            matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        Y                            matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        X                            matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        PosicindelefectorfinalLabel  matlab.ui.control.Label
        CinemticadirectaLabel        matlab.ui.control.Label
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        J6Slider                     matlab.ui.control.Slider
        J6SliderLabel                matlab.ui.control.Label
        J5Slider                     matlab.ui.control.Slider
        J5SliderLabel                matlab.ui.control.Label
        J4Slider                     matlab.ui.control.Slider
        J4SliderLabel                matlab.ui.control.Label
        J3Slider                     matlab.ui.control.Slider
        J3SliderLabel                matlab.ui.control.Label
        J2Slider                     matlab.ui.control.Slider
        J2SliderLabel                matlab.ui.control.Label
        J1Slider                     matlab.ui.control.Slider
        J1SliderLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Robot;
    end
    
    methods (Access = public)
        
        function draw(app)
            xlim([-1000 1000])
            ylim([-1000 1000])
            zlim([0 1500])
            tht1=app.J1Slider.Value;
            tht2=app.J2Slider.Value;
            tht3=app.J3Slider.Value;
            tht4=app.J4Slider.Value;
            tht5=app.J5Slider.Value;
            tht6=app.J6Slider.Value;
            q=[tht1,tht2,tht3,tht4,tht5,tht6]
            app.UIAxes.Visible='on';
            app.Robot.plot(q)
            PMTH=app.Robot.fkine(q);
            X=[transl(PMTH);flip(tr2rpy(PMTH,'zyx')')];
            app.X.Text =string(X(1));
            app.Y.Text=string(X(2));
            app.Z.Text=string(X(3));
            app.phi.Text=string(X(4));
            app.theta.Text=string(X(5));
            app.psi.Text=string(X(6));
            hold on;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            l0 = 350; l1 = 150; l2 = 250+75; l3 = 450;l4 = 80;
            ws= [-1000 1000 -1000 1000 0 1500];
            plot_options = {'workspace',ws,'scale',0.5,'view',[-16 28], 'tilesize',2,  'ortho', 'lightpos',[2 2 10] };
            L(1) = Link('revolute'  ,'alpha',     0,'a',   0, 'd',  l0,'offset',    0, 'qlim',        [-8*pi/9 8*pi/9],   'modified');
            L(2) = Link('revolute'  ,'alpha', -pi/2,'a',  l1, 'd',   0,'offset',-pi/2, 'qlim',       [-7*pi/36 5*pi/6],   'modified');
            L(3) = Link('revolute'  ,'alpha',     0,'a',  l2, 'd',   0,'offset',    0, 'qlim',  [-241*pi/180 5*pi/12],   'modified');
            L(4) = Link('revolute'  ,'alpha', -pi/2,'a',   0, 'd',  l3,'offset',    0, 'qlim',    [-19*pi/18 19*pi/18],   'modified');
            L(5) = Link('revolute'  ,'alpha',  pi/2,'a',   0, 'd',   0,'offset',    0, 'qlim',        [-2*pi/3 2*pi/3],   'modified');
            L(6) = Link('revolute'  ,'alpha', -pi/2,'a',   0, 'd',   0,'offset',    0, 'qlim',            [-2*pi 2*pi],   'modified');

            app.Robot = SerialLink(L,'name','Robot','plotopt',plot_options);
            app.Robot.tool = [1,0,0,0;
                              0,1,0,0;
                              0,0,1,l4;
                              0,0,0,1];
            app.UIFigure.HandleVisibility = 'callback';

        end

        % Value changed function: J1Slider
        function J1SliderValueChanged(app, event)
            value = app.J1Slider.Value;
            app.EditField.Value=value;
            draw(app);
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            value = app.J2Slider.Value;
             app.EditField_2.Value=value;
             draw(app);
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            value = app.J3Slider.Value;
             app.EditField_3.Value=value;
             draw(app);
        end

        % Value changed function: J4Slider
        function J4SliderValueChanged(app, event)
            value = app.J4Slider.Value;
             app.EditField_4.Value=value;
             draw(app);
        end

        % Value changed function: J5Slider
        function J5SliderValueChanged(app, event)
            value = app.J5Slider.Value;
             app.EditField_5.Value=value;
             draw(app);
        end

        % Value changed function: J6Slider
        function J6SliderValueChanged(app, event)
            value = app.J6Slider.Value;
             app.EditField_6.Value=value;
             draw(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
             app.J1Slider.Value=value;
             draw(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
              app.J2Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
              app.J3Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
              app.J4Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
              app.J5Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
              app.J6Slider.Value=value;
              draw(app);
        end

        % Button pushed function: DibujarTrayectoriaButton
        function DibujarTrayectoriaButtonPushed(app, event)
            H=700;
            a = 0.18*H; %distancia en x
            b = 0.1*H;  %distancia en y
            c = 0.08*H; %centro en y de los semicirculos
            r = 0.02*H; %radio 
            delta = 10;
            %%Ruta 1
            x = -a:delta:a;
            len = size(x);
            y = -b*ones(len);
            z = zeros(len);
            R1 = [x;y;z];
            traye = R1;
            
            %%Ruta 2
            t = 3*pi/2:delta/r:2*pi;
            x = r*cos(t)+a;
            y = r*sin(t)-c;
            len = size(x);
            z = zeros(len);
            R1 = [x;y;z];
            traye = [traye,R1];
            
            %%Ruta 3
            y = -c:delta:c;
            len = size(y);
            x = (a+r)*ones(len);
            z = zeros(len);
            R1 = [x;y;z];
            traye = [traye,R1];
            
            %%Ruta 4
            t = 0:delta/r:pi/2;
            x = r*cos(t)+a;
            y = r*sin(t)+c;
            len = size(x);
            z = zeros(len);
            R1 = [x;y;z];
            traye = [traye,R1];
            
            %%Ruta 5
            x = a:-delta:-a;
            len = size(x);
            y = b*ones(len);
            z = zeros(len);
            R1 = [x;y;z];
            traye = [traye,R1];
            
            %%Ruta 6
            t = pi/2:delta/r:pi;
            x = r*cos(t)-a;
            y = r*sin(t)+c;
            len = size(x);
            z = zeros(len);
            R1 = [x;y;z];
            traye = [traye,R1];
            
            %%Ruta 7
            y = c:-delta:-c;
            len = size(y);
            x = -(a+r)*ones(len);
            z = zeros(len);
            R1 = [x;y;z];
            traye = [traye,R1];
            
            %%Ruta 8
            t = pi:delta/r:3*pi/2;
            x = r*cos(t)-a;
            y = r*sin(t)-c;
            len = size(x);
            z = zeros(len);
            R1 = [x;y;z];
            
            traye = [traye,R1];
            %Matriz de Rotacion
            R = [1/sqrt(2),0,-1/sqrt(2);
                 0        ,1,0         ;
                 1/sqrt(2),0, 1/sqrt(2)];
            % Desplazamiento
            x = 500;
            y = 0;
            z = 400;
            
            % Rotacion y dezplazamiento de la trayectoria
            len = size(traye);
            for i = 1:len(2)
                traye(1:3,i) = R*traye(1:3,i);
            end
            
            traye(1,:) = x + traye(1,:);
            traye(2,:) = y + traye(2,:);
            traye(3,:) = z + traye(3,:);
            plot3(traye(1,:),traye(2,:),traye(3,:))
            xlim([-1000 1000])
            ylim([-1000 1000])
            zlim([0 1500])
            hold on
            l0 = 350; l1 = 150; l2 = 250+75; l3 = 450;l4 = 80;
            ws= [-1000 1000 -1000 1000 0 1500];
            plot_options = {'workspace',ws,'scale',0.5,'view',[-16 28], 'tilesize',2,  'ortho', 'lightpos',[2 2 10] };
            L(1) = Link('revolute'  ,'alpha',     0,'a',   0, 'd',  l0,'offset',    0, 'qlim',        [-8*pi/9 8*pi/9],   'modified');
            L(2) = Link('revolute'  ,'alpha', -pi/2,'a',  l1, 'd',   0,'offset',-pi/2, 'qlim',       [-7*pi/36 5*pi/6],   'modified');
            L(3) = Link('revolute'  ,'alpha',     0,'a',  l2, 'd',   0,'offset',    0, 'qlim',  [-241*pi/180 5*pi/12],   'modified');
            L(4) = Link('revolute'  ,'alpha', -pi/2,'a',   0, 'd',  l3,'offset',    0, 'qlim',    [-19*pi/18 19*pi/18],   'modified');
            L(5) = Link('revolute'  ,'alpha',  pi/2,'a',   0, 'd',   0,'offset',    0, 'qlim',        [-2*pi/3 2*pi/3],   'modified');
            L(6) = Link('revolute'  ,'alpha', -pi/2,'a',   0, 'd',   0,'offset',    0, 'qlim',            [-2*pi 2*pi],   'modified');

            Robot = SerialLink(L,'name','Robot','plotopt',plot_options);
            Robot.tool = [1,0,0,0;
              0,1,0,0;
              0,0,1,l4;
              0,0,0,1];
            syms theta1 theta2 theta3 theta4 theta5 theta6
            MTH01 = L(1).A(theta1);
            MTH12 = L(2).A(theta2);
            MTH23 = L(3).A(theta3);
            MTH34 = L(4).A(theta4);
            MTH45 = L(5).A(theta5);
            MTH56 = L(6).A(theta6);
            MTH6T = Robot.tool;
            MTH02 = MTH01*MTH12;
            MTH03 = MTH02*MTH23;
            MTH04 = MTH03*MTH34;
            MTH05 = MTH04*MTH45;
            MTH06 = MTH05*MTH56;
            MTH0T = MTH06*MTH6T;
            J1 = [cross(MTH01(1:3,3),(MTH0T(1:3,4)-MTH01(1:3,4)));MTH01(1:3,3)];
            J2 = [cross(MTH02(1:3,3),(MTH0T(1:3,4)-MTH02(1:3,4)));MTH02(1:3,3)];
            J3 = [cross(MTH03(1:3,3),(MTH0T(1:3,4)-MTH03(1:3,4)));MTH03(1:3,3)];
            J4 = [cross(MTH04(1:3,3),(MTH0T(1:3,4)-MTH04(1:3,4)));MTH04(1:3,3)];
            J5 = [cross(MTH05(1:3,3),(MTH0T(1:3,4)-MTH05(1:3,4)));MTH05(1:3,3)];
            J6 = [cross(MTH06(1:3,3),(MTH0T(1:3,4)-MTH06(1:3,4)));MTH06(1:3,3)];
            Jsim = [J1,J2,J3,J4,J5,J6];
            MTH0T = double(subs(MTH0T,[theta1,theta2,theta3,theta4,theta5,theta6],[0,0,0,0,0,0]));
            R = tr2rt(MTH0T)*roty(-pi/4);
            R = r2t(R);
            for i=1:size(traye,2)
                t_act=traye(:,i);
                T = transl(t_act)*R;    
                q_act = Robot.ikunc(T);    
                if i==1
                    q = q_act;
                else
                    J = double(subs(Jsim,[theta1,theta2,theta3,theta4,theta5,theta6],q_pas));
                    vecT=(t_act-t_pas);
                    vecT=vecT/norm(vecT)*500;
                    q_dot_act = (J^-1*[vecT;0;0;0])';
                    if i==2
                        q_dot = q_dot_act;
                    else
                        q_dot = [q_dot;q_dot_act];
                    end
                    
                    q = [q;q_act];
                end   
                t_pas = t_act;
                q_pas = q_act;
            end
            app.UIAxes.Visible='on';
            Robot.plot(q)
            view([-16 28])
            
            
            figure()
            for i=1:6
                subplot(2,3,i)
                plot(q(:,i))
                title((sprintf('q%d', i)))
            end
            figure()
            for i=1:6
                subplot(2,3,i)
                plot(q_dot(:,i))
                title((sprintf('qdot%d', i)))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FANUC LRMate 200iB RJ3iB')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.J1SliderLabel = uilabel(app.UIFigure);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.UIFigure);
            app.J1Slider.Limits = [-2.79252680319093 2.79252680319093];
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @J1SliderValueChanged, true);
            app.J2SliderLabel = uilabel(app.UIFigure);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.UIFigure);
            app.J2Slider.Limits = [-0.610865238198015 2.61799387799149];
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J3SliderLabel = uilabel(app.UIFigure);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.UIFigure);
            app.J3Slider.Limits = [-4.20624349730633 1.30899693899575];
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.J4SliderLabel = uilabel(app.UIFigure);
            app.J4SliderLabel.Text = 'J4';
            app.J4Slider = uislider(app.UIFigure);
            app.J4Slider.Limits = [-3.31612557878923 3.31612557878923];
            app.J4Slider.ValueChangedFcn = createCallbackFcn(app, @J4SliderValueChanged, true);
            app.J5SliderLabel = uilabel(app.UIFigure);
            app.J5SliderLabel.Text = 'J5';
            app.J5Slider = uislider(app.UIFigure);
            app.J5Slider.Limits = [-2.0943951023932 2.0943951023932];
            app.J5Slider.ValueChangedFcn = createCallbackFcn(app, @J5SliderValueChanged, true);
            app.J6SliderLabel = uilabel(app.UIFigure);
            app.J6SliderLabel.Text = 'J6';
            app.J6Slider = uislider(app.UIFigure);
            app.J6Slider.Limits = [-6.28318530717959 6.28318530717959];
            app.J6Slider.ValueChangedFcn = createCallbackFcn(app, @J6SliderValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.CinemticadirectaLabel = uilabel(app.UIFigure);
            app.CinemticadirectaLabel.Text = 'Cinemática directa';
            app.PosicindelefectorfinalLabel = uilabel(app.UIFigure);
            app.PosicindelefectorfinalLabel.Text = 'Posición del efector final';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.X = uilabel(app.UIFigure);
            app.X.Text = '';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.Y = uilabel(app.UIFigure);
            app.Y.Text = '';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.Z = uilabel(app.UIFigure);
            app.Z.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'φ:';
            app.phi = uilabel(app.UIFigure);
            app.phi.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'θ:';
            app.theta = uilabel(app.UIFigure);
            app.theta.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'ψ:';
            app.psi = uilabel(app.UIFigure);
            app.psi.Text = '';
            app.DibujarTrayectoriaButton = uibutton(app.UIFigure, 'push');
            app.DibujarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarTrayectoriaButtonPushed, true);
            app.DibujarTrayectoriaButton.Text = 'Dibujar Trayectoria';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end